classdef app2_bode_working < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        KV                            matlab.ui.control.NumericEditField
        KvLabel                       matlab.ui.control.Label
        DevelopedbyFuyukiMMuzyeKaueSilvadosSantosLabel  matlab.ui.control.Label
        ftcontroladorTextAreaLabel    matlab.ui.control.Label
        ftcontroladorTextArea         matlab.ui.control.TextArea
        Gs_____________________Label  matlab.ui.control.Label
        DefinaaFunotransfernciaLabel  matlab.ui.control.Label
        GerarcompensadorButton        matlab.ui.control.Button
        den                           matlab.ui.control.EditField
        num                           matlab.ui.control.EditField
        MG                            matlab.ui.control.NumericEditField
        MF                            matlab.ui.control.NumericEditField
        MGLabel                       matlab.ui.control.Label
        MFLabel                       matlab.ui.control.Label
        PreenchaoscamposaseguirparaprojetarocompensadorLabel  matlab.ui.control.Label
        Projetocontroladorprocessotipo1Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GerarcompensadorButton
        function GerarcompensadorButtonPushed(app, event)
    % Desabilita botão para evitar clique duplo
    app.GerarcompensadorButton.Enable = 'off';
    
    try
        % Entrada do usuário
        KV = app.KV.Value;
        MF = app.MF.Value;
        MG = app.MG.Value;

        num = str2num(app.num.Value); %#ok<ST2NM>
        den = str2num(app.den.Value); %#ok<ST2NM>

        s = tf('s');
        G = tf(num, den);

        %% Satisfazer o requisito da constante de erro estático
K = KV / (dcgain(s * G))  % dcgain calcula o valor em s → 0
G1=K*G
%Gráfico bode G e G1
figure;
bodeplot(G, G1);
legend('G', 'G1', 'Location', 'southwest');
[Gm,MF1,Wcg,Wcp1] = margin(G1); %Pm = Margem de fase; Wcp=Frequência de corte; Gm=margem de ganho
MF1
Wcp1
app.ftcontroladorTextArea.Value = {
            sprintf('Margem de fase G1 = %.4f', MF1)
            sprintf('--------------------------')
            };
%% 
phi= MF - MF1 + 5  % Avanço de fase máximo requerido.
alpha = (1 - sind(phi)) / (1 + sind(phi))
%%
Gc_=1/((alpha)^(1/2))
Gcdb=20*log10(Gc_) %dB a ser atingido
%% linha em -Gcdb e ponto de cruzamento com G1 ---

% Obter dados de Bode de G1
[mag, phase, w] = bode(G1);
mag = squeeze(mag);  % Remove dimensões extras
mag_dB = 20*log10(mag);
w = squeeze(w);

% Encontrar a frequência onde |G1(jw)| ≈ -Gcdb dB
% Interpolação para encontrar frequência onde mag_dB == -Gcdb
w_cross = interp1(mag_dB, w, -Gcdb, 'linear');  % agora mais preciso
mag_cross = interp1(w, mag_dB, w_cross);        % reavalia para confirmar
phase_cross = interp1(w, squeeze(phase), w_cross);  % Fase interpolada (opcional)

% Novo gráfico de Bode com marcações
figure;
h = bodeplot(G1);
grid on;
title('G1 com linha horizontal em -Gcdb e ponto de cruzamento');

% Espera o gráfico ser renderizado
drawnow;

% Acessa o eixo de magnitude
ax = findall(gcf, 'Type', 'axes');
mag_ax = ax(2);  % eixo da magnitude

% Linha horizontal em -Gcdb dB
yline(mag_ax, -Gcdb, '--r', sprintf('-%.2f dB', Gcdb), ...
    'LabelHorizontalAlignment', 'left', ...
    'LabelVerticalAlignment', 'bottom', ...
    'FontWeight', 'bold');

% Marcar o ponto de cruzamento
hold(mag_ax, 'on');
semilogx(mag_ax, w_cross, mag_cross, 'ks', ...
    'MarkerSize', 8, 'MarkerFaceColor', 'r');
text(w_cross, mag_cross, sprintf('  (%.2f rad/s)', w_cross), ...
    'Parent', mag_ax, 'Color', 'r', 'FontWeight', 'bold', ...
    'VerticalAlignment', 'bottom');
%% localização do zero do controlador 1/T = zero
zero=w_cross * ((alpha)^(1/2))
%% localização do polo do controlador 1/Ta = zero
pole = zero/alpha
%% localização do ganho do controlador KC
KC=K/alpha
%% Controlador e resposta da planta
Gc=KC*(s+zero)/(s+pole)
GGc=G*Gc
[GmGGc,MFGGc,WcgGGc,WcpGGc] = margin(GGc);
figure;
bodeplot(GGc,Gc,G,G1)
legend('GGc','Gc','G','G1', 'Location', 'southwest');
%% Verificação do erro estático (entrada rampa)
Kv_calc = dcgain(s * GGc)  % s*GGc para constante de velocidade
ess_rampa = 1 / Kv_calc;
MFGGc
GmGGc
%% Resposta ao degrau da malha fechada original vs compensada

% Malha fechada com realimentação unária (feedback = 1)
T_original = feedback(G, 1);  % com ganho K, mas sem compensador
T_compensado = feedback(GGc, 1);  % G * Gc

% Resposta ao degrau
t = 0:0.01:5;  % tempo de simulação
[y_orig, t_out] = step(T_original, t);
[y_comp, ~] = step(T_compensado, t);

% Gráfico
figure;
plot(t_out, y_orig, 'b--', 'LineWidth', 1.5);
hold on;
plot(t_out, y_comp, 'r-', 'LineWidth', 1.8);
grid on;
legend('Original (G)', 'Compensado (G*Gc)', 'Location', 'southeast');
xlabel('Tempo (s)');
ylabel('Saída');
title('Resposta ao Degrau: Sistema Original vs Compensado');

        % Exibe no TextArea
        app.ftcontroladorTextArea.Value = {
            sprintf('Gc(s) = %.4f * (s + %.4f) / (s + %.4f)', KC, zero, pole)
            sprintf('Kv calculado = %.4f', Kv_calc)
            sprintf('Kv desejado= %.4f', Kv_calc)
            sprintf('Margem de fase = %.4f', MFGGc)
            sprintf('Margem de fase desejada = %.4f', MF)
            sprintf('Margem de fase = %.4f', GmGGc)
            sprintf('Margem de fase desejada > %.4f', MG)
                        
            };
        
    catch ME
        % Mostra erro no TextArea se algo falhar
        app.ftcontroladorTextArea.Value = {
            'Erro ao gerar compensador:'
            ME.message
            };
    end

    % Reabilita o botão
    app.GerarcompensadorButton.Enable = 'on';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Projetocontroladorprocessotipo1Label = uilabel(app.UIFigure);
            app.Projetocontroladorprocessotipo1Label.Text = 'Projeto controlador processo tipo 1';
            app.PreenchaoscamposaseguirparaprojetarocompensadorLabel = uilabel(app.UIFigure);
            app.PreenchaoscamposaseguirparaprojetarocompensadorLabel.Text = 'Preencha os campos a seguir para projetar o compensador';
            app.MFLabel = uilabel(app.UIFigure);
            app.MFLabel.Text = 'MF';
            app.MGLabel = uilabel(app.UIFigure);
            app.MGLabel.Text = 'MG';
            app.MF = uieditfield(app.UIFigure, 'numeric');
            app.MF.Value = 55;
            app.MG = uieditfield(app.UIFigure, 'numeric');
            app.MG.Value = 6;
            app.num = uieditfield(app.UIFigure, 'text');
            app.num.Value = '[4]';
            app.den = uieditfield(app.UIFigure, 'text');
            app.den.Value = '[1 10 0]';
            app.GerarcompensadorButton = uibutton(app.UIFigure, 'push');
            app.GerarcompensadorButton.ButtonPushedFcn = createCallbackFcn(app, @GerarcompensadorButtonPushed, true);
            app.GerarcompensadorButton.Text = 'Gerar compensador';
            app.DefinaaFunotransfernciaLabel = uilabel(app.UIFigure);
            app.DefinaaFunotransfernciaLabel.Text = 'Defina a Função transferência';
            app.Gs_____________________Label = uilabel(app.UIFigure);
            app.Gs_____________________Label.Text = 'G(s) = _____________________';
            app.ftcontroladorTextArea = uitextarea(app.UIFigure);
            app.ftcontroladorTextArea.Editable = 'off';
            app.ftcontroladorTextAreaLabel = uilabel(app.UIFigure);
            app.ftcontroladorTextAreaLabel.Text = 'ft controlador';
            app.DevelopedbyFuyukiMMuzyeKaueSilvadosSantosLabel = uilabel(app.UIFigure);
            app.DevelopedbyFuyukiMMuzyeKaueSilvadosSantosLabel.Text = 'Developed by: Fuyuki M. Muzy e Kaue Silva dos Santos';
            app.KvLabel = uilabel(app.UIFigure);
            app.KvLabel.Text = 'Kv';
            app.KV = uieditfield(app.UIFigure, 'numeric');
            app.KV.Value = 16;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2_bode_working

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end